classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UploadImageButton     matlab.ui.control.Button
        InpUIAxes             matlab.ui.control.UIAxes
        OpenNoiseToolButton   matlab.ui.control.Button
        OutUIAxes             matlab.ui.control.UIAxes
        SaveButton            matlab.ui.control.Button
        OpenFilterToolButton  matlab.ui.control.Button
        DropDownLabel         matlab.ui.control.Label
        DropDown              matlab.ui.control.DropDown
        Button                matlab.ui.control.Button
    end


    properties (Access = public)
        filepath; % filepath of the input image
        filterImg % for filtering image files
        inputImg % input image
        defaultPic % default folder for pics
        outputImg % output image
        
    end

    methods (Access = public)
        
        function mainCallback(app, arg, img)
            app.outputImg = img;
            disp('I am in main call back');
            disp(size(app.outputImg));
            imshow(img, 'Parent', app.OutUIAxes);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, s)
            title(app.InpUIAxes, []);
            xlabel(app.InpUIAxes, []);
            ylabel(app.InpUIAxes, []);
            app.InpUIAxes.XAxis.TickLabels = {};
            app.InpUIAxes.YAxis.TickLabels = {};
            
            title(app.OutUIAxes, []);
            xlabel(app.OutUIAxes, []);
            ylabel(app.OutUIAxes, []);
            app.OutUIAxes.XAxis.TickLabels = {};
            app.OutUIAxes.YAxis.TickLabels = {};   
            
            app.defaultPic = './default_pics/';
            app.filterImg = {'*.jpg';'*.jpeg';'*.png';'*.tif' };
        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            [file,path] = uigetfile(app.filterImg, ...
                'Select an image');
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,file)]);
                 app.inputImg = imread([path,file]);
                 app.filepath = strcat(path,file);
                 disp(app.filepath);
                 imshow(app.inputImg, 'Parent', app.InpUIAxes);
            end
        end

        % Button pushed function: OpenNoiseToolButton
        function OpenNoiseToolButtonPushed(app, event)
            if (~ isempty(app.inputImg))
                noise(app,app.inputImg); 
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if (~isempty(app.outputImg))
                [file, path] = uiputfile(app.filterImg, 'Workspace File');
                if isequal(file,0) || isequal(path,0)
                    disp('User clicked Cancel.')
                else
                    disp(['User selected ',fullfile(path,file),...
                        ' and then clicked Save.'])
                    filePath = strcat(path,file);
                    imwrite(app.outputImg, filePath);
                end
            end
        end

        % Button pushed function: OpenFilterToolButton
        function OpenFilterToolButtonPushed(app, event)
            if (~ isempty(app.inputImg))
                filterapp(app,app.inputImg); 
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if (strcmp(app.DropDown.Value,'Compress Image'))
                x = app.inputImg;
                
                [rows, cols] = size(x);
                DF = zeros(rows, cols);
                DFF = zeros(rows, cols);
                IDF = zeros(rows, cols);
                IDFF = zeros(rows, cols);
                depth = 2;
                blockSize = 8; 
                
                for cont1 = 1: blockSize:rows
                    for cont2 = 1: blockSize:cols
                    		f = x(cont1:cont1+ blockSize-1, cont2:cont2+ blockSize-1);
                    df = dct2(f);
                    DF (cont1:cont1+ blockSize-1, cont2:cont2+blockSize-1) = df; %dct of block
                    dff = idct2(df);
                    DFF(cont1:cont1+ blockSize-1, cont2:cont2+blockSize-1) = dff; %idct of block
                    
                    df(blockSize:-1:depth+1, :) = 0;
                    df(:, blockSize:-1:depth+1) = 0;
                    IDF(cont1:cont1+blockSize-1, cont2:cont2+blockSize-1) = df; %combing dct blocks into image
                    dff = idct2(df);
                    IDFF(cont1:cont1+blockSize-1, cont2:cont2+blockSize-1) = dff; %combing dct blocks into image
                    end
                end
                app.outputImg = uint8(IDFF);
                imshow(uint8(IDFF), 'Parent', app.OutUIAxes);
            end
            if (strcmp(app.DropDown.Value,'Insert Visible Watermark'))
                x = app.inputImg;
                y = imread('Matlab.jpg');
                im = rgb2gray(x);
                im2 = rgb2gray(y);
               
                
                bit8_2 = bitget(im2, 8)*2^6;
                bit1 = bitget(im, 1)*2^0;
                bit2 = bitget(im, 2)*2^1;
                bit3 = bitget(im, 3)*2^2;
                bit4 = bitget(im, 4)*2^3;
                bit5 = bitget(im, 5)*2^4;
                bit6 = bitget(im, 6)*2^5;
                bit7 = bitget(im, 7)*2^6;
                bit8 = bitget(im, 8)*2^7;
                
                for i = 1:256
                    for j = 1:256
                        if(i < 180 || j < 180)
                            bit8_2(i,j) = bit7(i,j);
                        end
                    end
                end

                reconstructed = (bit1 + bit2 + bit3 + bit4 + bit5 + bit6 + bit8_2 + bit8);
                app.outputImg = reconstructed;
                imshow(reconstructed, 'Parent', app.OutUIAxes);
            end
            if (strcmp(app.DropDown.Value,'Insert Invisible Watermark'))
                x = app.inputImg;
                y = imread('Matlab.jpg');
                im = rgb2gray(x);
                im2 = rgb2gray(y);
                
                
                bit8_2 = bitget(im2, 2)*2^1;
                bit1 = bitget(im, 1)*2^0;
                bit2 = bitget(im, 2)*2^1;
                bit3 = bitget(im, 3)*2^2;
                bit4 = bitget(im, 4)*2^3;
                bit5 = bitget(im, 5)*2^4;
                bit6 = bitget(im, 6)*2^5;
                bit7 = bitget(im, 7)*2^6;
                bit8 = bitget(im, 8)*2^7;
                

                reconstructed = (bit1 + bit8_2 + bit3 + bit4 + bit5 + bit6 + bit7 + bit8);
                app.outputImg = reconstructed;
                imshow(reconstructed, 'Parent', app.OutUIAxes);
            end
            if (strcmp(app.DropDown.Value,'Remove S&P Noise'))
                Kaverage = medfilt2(app.inputImg);
                app.outputImg = Kaverage;
                imshow(Kaverage, 'Parent', app.OutUIAxes);
            end
            if (strcmp(app.DropDown.Value,'Remove Gaussian Noise'))
                K = wiener2(app.inputImg,[5 5]);
                app.outputImg = K;
                imshow(K, 'Parent', app.OutUIAxes);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.InpUIAxes = uiaxes(app.UIFigure);
            title(app.InpUIAxes, 'Title')
            xlabel(app.InpUIAxes, 'X')
            ylabel(app.InpUIAxes, 'Y')
            app.InpUIAxes.XLim = [0 1];
            app.InpUIAxes.YLim = [0 1];
            app.InpUIAxes.ZLim = [0 1];
            app.InpUIAxes.CLim = [0 1];
            app.InpUIAxes.XTick = [0 0.5 1];
            app.InpUIAxes.YTick = [0 0.5 1];
            app.InpUIAxes.ZTick = [0 0.5 1];
            app.OpenNoiseToolButton = uibutton(app.UIFigure, 'push');
            app.OpenNoiseToolButton.ButtonPushedFcn = createCallbackFcn(app, @OpenNoiseToolButtonPushed, true);
            app.OpenNoiseToolButton.Text = 'Open Noise Tool';
            app.OutUIAxes = uiaxes(app.UIFigure);
            title(app.OutUIAxes, 'Title')
            xlabel(app.OutUIAxes, 'X')
            ylabel(app.OutUIAxes, 'Y')
            app.OutUIAxes.XLim = [0 1];
            app.OutUIAxes.YLim = [0 1];
            app.OutUIAxes.ZLim = [0 1];
            app.OutUIAxes.CLim = [0 1];
            app.OutUIAxes.XTick = [0 0.5 1];
            app.OutUIAxes.YTick = [0 0.5 1];
            app.OutUIAxes.ZTick = [0 0.5 1];
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OpenFilterToolButton = uibutton(app.UIFigure, 'push');
            app.OpenFilterToolButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFilterToolButtonPushed, true);
            app.OpenFilterToolButton.Text = 'Open Filter Tool ';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Compress Image', 'Insert Visible Watermark', 'Insert Invisible Watermark', 'Remove S&P Noise', 'Remove Gaussian Noise'};
            app.DropDown.Value = 'Compress Image';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = mainapp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end